classdef apptrial1_version2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure                     matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        LoadVideoTab                   matlab.ui.container.Tab
        Frame1                         matlab.ui.control.UIAxes
        VideonameEditFieldLabel        matlab.ui.control.Label
        VideonameEditField             matlab.ui.control.EditField
        BrowseButton                   matlab.ui.control.Button
        NumberofframesEditFieldLabel   matlab.ui.control.Label
        NumberofframesEditField        matlab.ui.control.NumericEditField
        InitializingBackgroundPixelButton  matlab.ui.control.Button
        DetectcellsTab                 matlab.ui.container.Tab
        Frame1cells                    matlab.ui.control.UIAxes
        DetectButton                   matlab.ui.control.Button
        NumberDetectedEditFieldLabel   matlab.ui.control.Label
        NumberDetectedEditField        matlab.ui.control.NumericEditField
        AdvancedSettingsButton         matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        CategorizecellsTab             matlab.ui.container.Tab
        eyeframe                       matlab.ui.control.UIAxes
        noneyeframe                    matlab.ui.control.UIAxes
        NumberofDeformableCellsEditFieldLabel  matlab.ui.control.Label
        NumberofDeformableCellsEditField  matlab.ui.control.NumericEditField
        NumberofNonDeformableCellsEditFieldLabel  matlab.ui.control.Label
        NumberofNonDeformableCellsEditField  matlab.ui.control.NumericEditField
        CategorizeButton               matlab.ui.control.Button
        VideoCountTab                  matlab.ui.container.Tab
        Videoframe                     matlab.ui.control.UIAxes
        StartVideoButton               matlab.ui.control.Button
        numbertimegraph                matlab.ui.control.UIAxes
        PauseVideoButton               matlab.ui.control.Button
        NumberofCellsfoundEditFieldLabel  matlab.ui.control.Label
        NumberofCellsfoundEditField    matlab.ui.control.NumericEditField
        CurrenttimesEditFieldLabel     matlab.ui.control.Label
        CurrenttimesEditField          matlab.ui.control.NumericEditField
        ResumeVideoButton              matlab.ui.control.Button
        RESETVIDEOButton               matlab.ui.control.Button
        VideoTrackingTab               matlab.ui.container.Tab
        Videotrackframe                matlab.ui.control.UIAxes
        StarttrackingButton            matlab.ui.control.Button
        NumberofCellsfoundEditField_2Label  matlab.ui.control.Label
        NumberofCellsfoundEditField_2  matlab.ui.control.NumericEditField
        CurrenttimesEditField_2Label   matlab.ui.control.Label
        CurrenttimesEditField_2        matlab.ui.control.NumericEditField
        MethodEditFieldLabel           matlab.ui.control.Label
        MethodEditField                matlab.ui.control.NumericEditField
        SubMethodEditFieldLabel        matlab.ui.control.Label
        SubMethodEditField             matlab.ui.control.NumericEditField
        SingleCelltrackingButton       matlab.ui.control.Button
        AvgvelpsEditFieldLabel         matlab.ui.control.Label
        AvgvelpsEditField              matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        video % video file name
        frames1 %frame1 grayscale
        J %filtered grayscale frame1
        Gmag %magnitude of gradient of frame 1
        imgfilter1 % filtered bw image frame1
        BW2 % bw image frame1
        n % current frame number
        t % current time
        centroids1 %centroid coordinates of cells in frame 1
        flag=0; % flag for checking if advanced settings have been called or not
        pausevalue = 0; %pause value indicate if the 'pausevideo' button has been called or not 
        resumevalue =0; %resume value indicate if the 'resumevideo' button has been called or not 
        Gthresh =400;
        normalizedBackground=0.35;
        c; % number of frames to be analyzed
    end

    methods (Access = public)

            
            function [xf, yf] = findCloseCentroid (app,x,y,centroidAll)
            
                A = [x y];
                B = centroidAll;
                distances = sqrt(sum(bsxfun(@minus, B, A).^2,2));
                deltaY = B(:,2)-A(2);
                opDistance = distances+deltaY;

                %closest = B(find(distances==min(distances)),:);
                %if (min(distances)<20)
    
                closest = B(distances==min(distances),:);
                %closest = B(opDistance==min(opDistance),:);
                xf = closest(1,1);
                yf = closest(1,2);
                %end
            
            
            end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AdvancedSettingsButton
        function AdvancedSettingsButtonPushed(app, event)
            fig = uifigure('Name','Advanced settings');
            
            btn1 = uibutton(fig,'push',...
                   'Text', 'Counted Extra?',...
                   'Position',[50,200, 100, 22],...
                   'ButtonPushedFcn', @(btn,event) Btn1Pushed(btn));
               
            btn2 = uibutton(fig,'push',...
                   'Text', 'Counted Less?',...
                   'Position',[50,100, 100, 22],...
                   'ButtonPushedFcn', @(btn,event) Btn2Pushed(btn)); 
               
            edt = uieditfield(fig,'numeric',...
                            'Position',[200 100 100 22],...
                            'ValueChangedFcn',@(txt,event) textChanged(txt));
            
               
                   function Btn1Pushed(btn)  
                       figure,imshow(app.frames1)
                       figure
                       app.BW2 = bwselect(app.imgfilter1);
                       app.BW2 = app.imgfilter1-app.BW2;
                       app.BW2 = logical(app.BW2);
                       close 
                       close
                       app.flag=1;
                   end
                   
                   
                   function Btn2Pushed(btn) 
                                figure,plot(app.Gmag)
                                 
                                
                   end
                     function textChanged(txt)
                              app.Gthresh = txt.Value;


                               binaryImage = app.Gmag > app.Gthresh;   
                               % Connect high Gmag values to get objects   
                               binaryImage = bwareaopen (binaryImage,10);
                               se2 = strel('line',10,0);
                               se = strel('disk',4,6);
                               app.imgfilter1= imdilate(binaryImage,se);
                               app.imgfilter1= imdilate(app.imgfilter1,se2);
                               app.BW2 = app.imgfilter1;
                               app.flag=1;
            
                     end 
                     
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file,~] = uigetfile('*.avi');
            app.video=file;
            app.VideonameEditField.Value = app.video;
            VideonameEditFieldValueChanged(app,event)
        end

        % Button pushed function: CategorizeButton
        function CategorizeButtonPushed(app, event)
            % round centroid matrix as grind idices can not be double
            rcentroids1 = round(app.centroids1);
            e=1;
            ne=1;
            
       for i = 1 : size(app.centroids1,1)
            
            
            % create horizontal range of grid matrices to be traversed to
            % look for unimodal or bimodal grayscale index variation 
            
            x=rcentroids1(i,1)-15:rcentroids1(i,1)+15; 
            x =  x(x>0 &x<size(app.J,2));                           
            Ix = app.J(rcentroids1(i,2),x);
            Ix = im2double(Ix);
            
            % Imp : read documentation for 'findpeaks' on matlab
            % for better understanding and improving the classification of
            % eye and non-eye results.
            % output 'locs' are 'x' values at which relevant peaks occur
            % only correct if loc is 1 or 2 element(s) array : unimodal or
            % bimodal, respectively
            
            [~,locs] =findpeaks(Ix,x,'MinPeakHeight',0.35,'MinPeakdistance',8);
            
            if(length(locs)==1) % unimodal -> non-eye
                noneye(ne,1) = locs;
                noneye(ne,2) = rcentroids1(i,2);
                ne=ne+1;
            end
            if(length(locs) ==2) % bimodal -> eye
                eye(e,1) = locs(1);
                eye(e,2) = rcentroids1(i,2);
                e=e+1;
            end
            
            
      end
     
            %% Final Classification result
     radius1=12*ones(size(app.centroids1,1),1);
     imgeye = insertShape(app.frames1,'circle',[eye(:,1) eye(:,2) radius1(1:size(eye,1))],'Color','green','LineWidth',2);
     imshow(imgeye,'Parent',app.eyeframe, ...
                 'XData', [1 app.eyeframe.Position(3)], ...   
                 'YData', [1 app.eyeframe.Position(4)]);
     app.NumberofDeformableCellsEditField.Value = size(eye,1);
              
     
     imgnoneye = insertShape(app.frames1,'circle',[noneye(:,1) noneye(:,2) radius1(1:size(noneye,1))],'Color','cyan','LineWidth',2);
     imshow(imgnoneye,'Parent',app.noneyeframe, ...
                 'XData', [1 app.noneyeframe.Position(3)], ...   
                 'YData', [1 app.noneyeframe.Position(4)]);
     app.NumberofNonDeformableCellsEditField.Value = size(noneye,1);
              
     
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
        % filtering the frame to create clear foreground-background distinction
        % using top-hat filter
             if(~ app.flag)% if BW2 doesn't exist, create it, else use it 
             
            se = strel('disk',12);
            app.J = imtophat(app.frames1,se);    
            
        % Gradient magnitude
            [app.Gmag,~] = imgradient(app.frames1);


        % Thresholding using Gmag 
            binaryImage = app.Gmag > app.Gthresh;
            
            %% Removing VERY small objects
            small = app.J>50;
            small = bwareaopen (small,20);
            binaryImage = binaryImage.*small;


        % Connect high Gmag values to get objects   
            binaryImage = bwareaopen (binaryImage,10);
            se2 = strel('line',10,0);
            se = strel('disk',4,6);
            app.imgfilter1= imdilate(binaryImage,se);
            app.imgfilter1= imdilate(app.imgfilter1,se2);
            app.BW2 = app.imgfilter1;
            
            end
            s = regionprops(app.BW2,'centroid');
            app.centroids1 = cat(1, s.Centroid);
            
        % radius of marker circles
            radius1=12*ones(size(app.centroids1,1),1);
            foundI = insertShape(app.frames1,'circle',[app.centroids1(:,1) app.centroids1(:,2) radius1],'Color','yellow','LineWidth',2); 
            
            imshow(foundI,'Parent',app.Frame1cells);
             
            app.NumberDetectedEditField.Value = size(app.centroids1,1);
        end

        % Size changed function: DetectcellsTab
        function DetectcellsTabSizeChanged(app, event)
            position = app.DetectcellsTab.Position;
        end

        % Button pushed function: InitializingBackgroundPixelButton
        function InitializingBackgroundPixelButtonPushed(app, event)
           figure, imshow(app.frames1) 
           [column, row] = ginput(1);
           close
           backgroundGrayLevel = app.frames1(round(row), round(column));
           
           if backgroundGrayLevel<30
               app.Gthresh =150;
               app.normalizedBackground =0.1;
               
           elseif backgroundGrayLevel<50 && backgroundGrayLevel>=30
               app.Gthresh =300;
               app.normalizedBackground =0.4;
           
           elseif backgroundGrayLevel>50
               app.Gthresh =350;
               app.normalizedBackground =0.5;
           end
    
        end

        % Button pushed function: PauseVideoButton
        function PauseVideoButtonPushed(app, event)
            app.pausevalue =1;
        end

        % Button pushed function: RESETVIDEOButton
        function RESETVIDEOButtonPushed(app, event)
            app.pausevalue =0;
            app.resumevalue =0;
            cla(app.Videoframe)
            cla(app.numbertimegraph)
            app.NumberofCellsfoundEditField.Value=0;
            app.CurrenttimesEditField.Value =0;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.flag=0;
            app.pausevalue =0;
            app.Gthresh =400;
        end

        % Button pushed function: ResumeVideoButton
        function ResumeVideoButtonPushed(app, event)
            app.pausevalue =0;
            app.resumevalue =1;
        end

        % Button pushed function: SingleCelltrackingButton
        function SingleCelltrackingButtonPushed(app, event)
            figure, imshow(app.imgfilter1)
            title ('SELECT A SINGLE CELL and DOUBLE CLICK ON EMPTY AREA TO EXIT')
            app.BW2 = bwselect(app.imgfilter1);
            close;
            StarttrackingButtonPushed(app)
        end

        % Button pushed function: StartVideoButton
        function StartVideoButtonPushed(app, event)
            vid=VideoReader(app.video);
            T=vid.Duration;
            app.pausevalue=0;
            if (app.resumevalue==0)
                app.n =1;
                app.t=0;
            end
            to=0;
            
            dt=(T-to)/app.c;
         
            % Initializing number and time arrays for final number v time graph
            numbercells = zeros (1, app.c);
            time = zeros (1, app.c);
            numbercells(app.n) = size(app.centroids1,1);
            
            vid.CurrentTime = app.t+dt;
            app.n=app.n+1; 
             
            
           while (hasFrame(vid) && app.pausevalue ~= 1)
     %%
     frames2=readFrame(vid);
     frames2=rgb2gray(frames2);
     
     %top-hat-filter
     se = strel('disk',12);
     J2 = imtophat(frames2,se);
            
     %% normalization of indices of grayscale
     filterframes2=double(J2);
     filterframes2=filterframes2/255; 
     
     % CORRELATION : computed by simple index cross multiplication
     f2 = app.BW2 .* filterframes2;
     
     % May need to adjust parameters
     imgfilter2 = f2 >0.125;
     imgfilter2 = bwareaopen (imgfilter2,15);
     se = strel('disk',2,6);
     se2 = strel('line',10,0);
     imgfilter2= imdilate(imgfilter2,se);
     imgfilter2= imdilate(imgfilter2,se2);
     
     % calculating centroids and corresponding number of objects
     
     s = regionprops(imgfilter2,'centroid');
     centroids2 = cat(1, s.Centroid);
     numbercells(app.n) = size(centroids2,1);
     
     if (numbercells(app.n)>numbercells(app.n-1) ) % constraint cell counts to not increase with time  
        numbercells(app.n) = numbercells(app.n-1); 
     end
     
     if (size(centroids2,1)~=0)
         %radius of marker circle   
         radius2=20*ones(size(centroids2,1),1);
         foundI = insertShape(frames2,'circle',[centroids2(:,1) centroids2(:,2) radius2],'Color','yellow','LineWidth',2);
         imshow(foundI,'Parent',app.Videoframe);
     else
         imshow(frames2,'Parent',app.Videoframe);
     end
     
     time(app.n)=app.t;
     app.t=app.t+dt;
     app.NumberofCellsfoundEditField.Value=numbercells(app.n);
     app.CurrenttimesEditField.Value = app.t;
     
     if app.t<T
         vid.CurrentTime= min(app.t,T);
     else
         break;
     end
         app.n=app.n+1;
          end
            %% Final figure number v time
    plot (app.numbertimegraph,time,numbercells,'r-')
        end

        % Button pushed function: StarttrackingButton
        function StarttrackingButtonPushed(app, event)
            
            vid=VideoReader(app.video);
            T=vid.Duration;
            app.n =1;
            app.t=0;
            to=0;
            dt=(T-to)/app.c;
            method = app.MethodEditField.Value;
            submethod= app.SubMethodEditField.Value;
            
            % Initializing number and time arrays for final number v time graph
            numbercells = zeros (1, app.c);
            time = zeros (1, app.c);
            numbercells(app.n) = size(app.centroids1,1);
            sz = size(app.centroids1);
            A = zeros (sz(1,1),sz(1,2),app.c);
            
        for i=1:sz(1,1)
            A (i,1,app.n) = app.centroids1(i,1);
            A (i,2,app.n) = app.centroids1(i,2);
        end 
        
      if (method ==1)
          vid.CurrentTime = app.t+dt;
          app.n=app.n+1;
          while hasFrame(vid)
                frames2=readFrame(vid);
                frames2=rgb2gray(frames2);
                [app.Gmag,~] = imgradient(frames2);
                seF = strel('disk',12);
                J2 = imtophat(frames2,seF);
                binaryImage = app.Gmag > app.Gthresh;
                %figure, imshow(binaryImage)
                se = strel('disk',4,6);
                se2 = strel('line',10,0);
                imgfilterComplete= imdilate(binaryImage,se);
                imgfilterComplete= imdilate(imgfilterComplete,se2);
                %figure, imshow(imgfilterComplete)
                filterframes2=double(J2);
                filterframes2=filterframes2/255; 
                f2 = app.BW2 .* filterframes2;
                imgfilter2 = f2 > app.normalizedBackground;
                imgfilter2 = bwareaopen (imgfilter2,5);
                se = strel('disk',1,6);
                 se2 = strel('line',3,0);
                 imgfilter2= imdilate(imgfilter2,se);
                 imgfilter2= imdilate(imgfilter2,se2);
                 imgfilter2 = imfill(imgfilter2,'holes');  
                 s = regionprops(imgfilter2,'centroid');
                 centroidRelevant = cat(1, s.Centroid);
                 %figure, imshow(imgfilter2)
                  if (submethod ==1)
                        if (size (centroidRelevant,1)~=0)  
                        imgFinal = bwselect(imgfilterComplete,centroidRelevant(:,1)',centroidRelevant(:,2)',4);
                        %figure, imshow(imgFinal) 
                        %else **need to figure out later**
                        end
                  elseif(submethod ==2)
                         sa = regionprops(imgfilterComplete,'centroid');
                         centroidAll = cat(1, sa.Centroid);
                         %centroidTemp = zeros (size(centroidRelevant));
                        for cen =1: size(centroidRelevant,1)
                            [centroidTemp(cen,1),centroidTemp(cen,2)] = findCloseCentroid (centroidRelevant(cen,1),centroidRelevant(cen,2),centroidAll);
                        end
                        imgFinal = bwselect(imgfilterComplete,centroidTemp(:,1)',centroidTemp(:,2)',4);
                         %figure, imshow(imgFinal)
                  end
                    imgFinal = logical(imgFinal);   
                    sc = regionprops(imgFinal,'centroid');
                    centroids2 = cat(1, sc.Centroid);
                    % Sort centroids2 matrix along y's for lower computational cost 
                    numbercells(app.n) = size(centroids2,1);% number of rows
                    
                    app.BW2 = imgFinal;
                     
                     if (size(centroids2,1)~=0)
                            radius2=12*ones(size(centroids2,1),1);
                            foundI = insertShape(frames2,'circle',[centroids2(:,1) centroids2(:,2) radius2],'Color','yellow','LineWidth',2);
                            imshow(foundI,'Parent',app.Videotrackframe);
                            
                             sz2=size(centroids2);  
                             for i=1:sz2(1,1)
                                   A (i,1,app.n) = centroids2(i,1);
                                   A (i,2,app.n) = centroids2(i,2);
                             end
                            
                
                     else
                             imshow(frames2,'Parent',app.Videotrackframe);
                     end
                     time(app.n)=app.t;
                     app.t=app.t+dt;
                    
                     app.NumberofCellsfoundEditField_2.Value=numbercells(app.n);
                     app.CurrenttimesEditField_2.Value = app.t;
                     
                     if app.t<T
                         vid.CurrentTime= min(app.t,T);
                     else
                         break;
                     end
                     app.n=app.n+1;
          end
          
                      
      elseif (method==2)
          
            vid.CurrentTime = app.t+dt;
            app.n=app.n+1; 
             
            centroidst = app.centroids1;
    
            
            while hasFrame(vid)
    frames2=readFrame(vid);
    frames2=rgb2gray(frames2);
    [app.Gmag,~] = imgradient(frames2);
    se = strel('disk',12);
    J2 = imtophat(frames2,se);
    binaryImage = app.Gmag > app.Gthresh;
    se2 = strel('line',10,0);
    se = strel('disk',4,6);
    imgfilter2= imdilate(binaryImage,se);
    imgfilter2= imdilate(imgfilter2,se2);
    s = regionprops(imgfilter2,'centroid');
    centroids2 = cat(1, s.Centroid);
    % Sort centroids2 matrix along y's for lower computational cost 
        if (isempty(~centroids2))
            centroids2= sortrows(centroids2,2); 
        end
        
    % simple filter to remove flying cells -> increase the value of
    % gapdist if video is shaky
    gapdist =20;
    centroids2 = centroidFilter(centroidst,centroids2,gapdist); 
    numbercells(app.n) = size(centroids2,1);% number of rows
    sz2=size(centroids2);  
    
    if(numbercells(app.n)<=numbercells(app.n-1)) % if number of cells remain same no noise -> no filter needed 
        numbercells(app.n) = numbercells(app.n);
        
        for i=1:sz2(1,1)
            A (i,1,app.n) = centroids2(i,1);
            A (i,2,app.n) = centroids2(i,2);
        end
        
    else % that is noise is there, find the deviation delete it i.e. filter out the noise
         centroids2 = centroidFilter(centroidst,centroids2,45);
         numbercells(app.n) = size(centroids2,1); 
         
         if (numbercells(app.n)>numbercells(app.n-1)) % if noise is still there try better filter
              centroids2 = centroidFilter(centroidst,centroids2,20);
              numbercells(app.n) = size(centroids2,1);                
         end
         
         if (numbercells(app.n)>numbercells(app.n-1)) % if noise is still there try better filter
              centroids2 = centroidFilter(centroidst,centroids2,10);
              numbercells(app.n) = size(centroids2,1);                
         end   
         
         if (numbercells(app.n)>numbercells(app.n-1)) % if noise is still there try better filter
              centroids2 = centroidFilter(centroidst,centroids2,5);
              numbercells(app.n) = size(centroids2,1);                
         end   
         if (numbercells(app.n)>numbercells(app.n-1)) % still noise? remove cells gone from centroids1 and give that to centroids2   
                centroids2= centroidst;
                numbercells(app.n) = size(centroids2,1); 
         end
         
         sz2=size(centroids2); % size of centroid matrix changed after filtering
              
                for i=1:sz2(1,1)
                    A (i,1,app.n) = centroids2(i,1);
                    A (i,2,app.n) = centroids2(i,2);
                end
     end
     % update centroids1   
     centroidst=centroids2;
    
     if (size(centroids2,1)~=0)
         radius2=12*ones(size(centroids2,1),1);
          foundI = insertShape(frames2,'circle',[centroids2(:,1) centroids2(:,2) radius2],'Color','yellow');
          imshow(foundI,'Parent',app.Videotrackframe);
                
     else
          imshow(frames2,'Parent',app.Videotrackframe);
     end
            
     time(app.n)=app.t;
     app.t=app.t+dt;
     app.NumberofCellsfoundEditField_2.Value=numbercells(app.n);
     app.CurrenttimesEditField_2.Value = app.t;


     if app.t<T
     vid.CurrentTime= min(app.t,T);
     else
         break;
     end
         app.n=app.n+1;
            end
      end 
              figure
                plot (time,numbercells,'r-')
                title('Patient ID:xxxx FlowRate:yyyy')
                xlabel('t (s)')
                ylabel('N (Cell Count)')
                hold off
        
    imshow (app.J,'Parent',app.Videotrackframe)
    hold(app.Videotrackframe,'on');
    
               if (size (centroids2,1) ~=0)
    xf = centroids2(:,1);
    yf = centroids2(:,2);
    plot(app.Videotrackframe,xf,yf,'r*',...
        'MarkerSize',15)
      hold(app.Videotrackframe, 'on');
               end 
    for f =1:app.c 
        x = A(:,1,f);
        y = A(:,2,f);
        plot(app.Videotrackframe,x,y,'wo',...
           'MarkerSize',4)
       hold(app.Videotrackframe,'on');
    end
    
    if (nnz (A(:,:,2)) ==2) % result of single cell tracking
        for q = 2: size(A,3)-1
            if (A(1,1,q+1)~=0)
                v(q) = (((A(1,1,q) -A(1,1,q+1))^2 +(A(1,2,q) -A(1,2,q+1))^2)^0.5)/dt; 
            end
        end
        app.AvgvelpsEditField.Value = mean(v);
    end


        end

        % Value changed function: VideonameEditField
        function VideonameEditFieldValueChanged(app, event)
            value = app.VideonameEditField.Value;
            app.video = value;
            
            app.flag=0;
            app.pausevalue =0;
            
            
            vid=VideoReader(value);
            
            T=vid.Duration;
            app.t=0;
            to=0;
            
            % number of frames to be counted in the video
            % frames are calculated at equal separation of time
            
            app.c=app.NumberofframesEditField.Value; 
            dt=(T-to)/app.c;
            
            vid.CurrentTime = app.t;
            app.n=1;
            
            
            app.frames1=readFrame(vid);
            app.frames1=rgb2gray(app.frames1);
            
            imshow(app.frames1,'Parent',app.Frame1);    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.MainFigure);
            app.LoadVideoTab = uitab(app.TabGroup);
            app.LoadVideoTab.Title = 'Load Video';
            app.Frame1 = uiaxes(app.LoadVideoTab);
            app.VideonameEditFieldLabel = uilabel(app.LoadVideoTab);
            app.VideonameEditFieldLabel.Text = 'Video name';
            app.VideonameEditField = uieditfield(app.LoadVideoTab, 'text');
            app.VideonameEditField.ValueChangedFcn = createCallbackFcn(app, @VideonameEditFieldValueChanged, true);
            app.BrowseButton = uibutton(app.LoadVideoTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.NumberofframesEditFieldLabel = uilabel(app.LoadVideoTab);
            app.NumberofframesEditFieldLabel.Text = 'Number of frames';
            app.NumberofframesEditField = uieditfield(app.LoadVideoTab, 'numeric');
            app.NumberofframesEditField.Limits = [1 Inf];
            app.NumberofframesEditField.Value = 100;
            app.InitializingBackgroundPixelButton = uibutton(app.LoadVideoTab, 'push');
            app.InitializingBackgroundPixelButton.ButtonPushedFcn = createCallbackFcn(app, @InitializingBackgroundPixelButtonPushed, true);
            app.InitializingBackgroundPixelButton.Text = 'Initializing Background Pixel';
            app.DetectcellsTab = uitab(app.TabGroup);
            app.DetectcellsTab.SizeChangedFcn = createCallbackFcn(app, @DetectcellsTabSizeChanged, true);
            app.DetectcellsTab.Title = 'Detect cells';
            app.Frame1cells = uiaxes(app.DetectcellsTab);
            app.DetectButton = uibutton(app.DetectcellsTab, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            app.NumberDetectedEditFieldLabel = uilabel(app.DetectcellsTab);
            app.NumberDetectedEditFieldLabel.Text = 'Number Detected';
            app.NumberDetectedEditField = uieditfield(app.DetectcellsTab, 'numeric');
            app.AdvancedSettingsButton = uibutton(app.DetectcellsTab, 'push');
            app.AdvancedSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedSettingsButtonPushed, true);
            app.AdvancedSettingsButton.Text = 'Advanced Settings';
            app.ResetButton = uibutton(app.DetectcellsTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CategorizecellsTab = uitab(app.TabGroup);
            app.CategorizecellsTab.Title = 'Categorize cells';
            app.eyeframe = uiaxes(app.CategorizecellsTab);
            title(app.eyeframe, 'Deformable')
            app.noneyeframe = uiaxes(app.CategorizecellsTab);
            title(app.noneyeframe, 'Non-deformable')
            app.NumberofDeformableCellsEditFieldLabel = uilabel(app.CategorizecellsTab);
            app.NumberofDeformableCellsEditFieldLabel.Text = 'Number of Deformable Cells';
            app.NumberofDeformableCellsEditField = uieditfield(app.CategorizecellsTab, 'numeric');
            app.NumberofNonDeformableCellsEditFieldLabel = uilabel(app.CategorizecellsTab);
            app.NumberofNonDeformableCellsEditFieldLabel.Text = 'Number of Non-Deformable Cells';
            app.NumberofNonDeformableCellsEditField = uieditfield(app.CategorizecellsTab, 'numeric');
            app.CategorizeButton = uibutton(app.CategorizecellsTab, 'push');
            app.CategorizeButton.ButtonPushedFcn = createCallbackFcn(app, @CategorizeButtonPushed, true);
            app.CategorizeButton.Text = 'Categorize';
            app.VideoCountTab = uitab(app.TabGroup);
            app.VideoCountTab.Title = 'Video Count';
            app.Videoframe = uiaxes(app.VideoCountTab);
            app.StartVideoButton = uibutton(app.VideoCountTab, 'push');
            app.StartVideoButton.ButtonPushedFcn = createCallbackFcn(app, @StartVideoButtonPushed, true);
            app.StartVideoButton.Text = 'Start Video';
            app.numbertimegraph = uiaxes(app.VideoCountTab);
            title(app.numbertimegraph, 'Number against time')
            xlabel(app.numbertimegraph, 'time (in sec)')
            ylabel(app.numbertimegraph, 'Number of adhered cells')
            app.PauseVideoButton = uibutton(app.VideoCountTab, 'push');
            app.PauseVideoButton.ButtonPushedFcn = createCallbackFcn(app, @PauseVideoButtonPushed, true);
            app.PauseVideoButton.Text = 'Pause Video';
            app.NumberofCellsfoundEditFieldLabel = uilabel(app.VideoCountTab);
            app.NumberofCellsfoundEditFieldLabel.Text = 'Number of Cells found';
            app.NumberofCellsfoundEditField = uieditfield(app.VideoCountTab, 'numeric');
            app.CurrenttimesEditFieldLabel = uilabel(app.VideoCountTab);
            app.CurrenttimesEditFieldLabel.Text = 'Current time (s)';
            app.CurrenttimesEditField = uieditfield(app.VideoCountTab, 'numeric');
            app.ResumeVideoButton = uibutton(app.VideoCountTab, 'push');
            app.ResumeVideoButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeVideoButtonPushed, true);
            app.ResumeVideoButton.Text = 'Resume Video';
            app.RESETVIDEOButton = uibutton(app.VideoCountTab, 'push');
            app.RESETVIDEOButton.ButtonPushedFcn = createCallbackFcn(app, @RESETVIDEOButtonPushed, true);
            app.RESETVIDEOButton.Text = 'RESET VIDEO';
            app.VideoTrackingTab = uitab(app.TabGroup);
            app.VideoTrackingTab.Title = 'Video Tracking';
            app.Videotrackframe = uiaxes(app.VideoTrackingTab);
            app.StarttrackingButton = uibutton(app.VideoTrackingTab, 'push');
            app.StarttrackingButton.ButtonPushedFcn = createCallbackFcn(app, @StarttrackingButtonPushed, true);
            app.StarttrackingButton.Text = 'Start tracking';
            app.NumberofCellsfoundEditField_2Label = uilabel(app.VideoTrackingTab);
            app.NumberofCellsfoundEditField_2Label.Text = 'Number of Cells found';
            app.NumberofCellsfoundEditField_2 = uieditfield(app.VideoTrackingTab, 'numeric');
            app.CurrenttimesEditField_2Label = uilabel(app.VideoTrackingTab);
            app.CurrenttimesEditField_2Label.Text = 'Current time (s)';
            app.CurrenttimesEditField_2 = uieditfield(app.VideoTrackingTab, 'numeric');
            app.MethodEditFieldLabel = uilabel(app.VideoTrackingTab);
            app.MethodEditFieldLabel.Text = 'Method #';
            app.MethodEditField = uieditfield(app.VideoTrackingTab, 'numeric');
            app.MethodEditField.Limits = [1 2];
            app.MethodEditField.Value = 1;
            app.SubMethodEditFieldLabel = uilabel(app.VideoTrackingTab);
            app.SubMethodEditFieldLabel.Text = 'Sub-Method #';
            app.SubMethodEditField = uieditfield(app.VideoTrackingTab, 'numeric');
            app.SubMethodEditField.Limits = [1 2];
            app.SubMethodEditField.Value = 2;
            app.SingleCelltrackingButton = uibutton(app.VideoTrackingTab, 'push');
            app.SingleCelltrackingButton.ButtonPushedFcn = createCallbackFcn(app, @SingleCelltrackingButtonPushed, true);
            app.SingleCelltrackingButton.Text = 'Single Cell tracking';
            app.AvgvelpsEditFieldLabel = uilabel(app.VideoTrackingTab);
            app.AvgvelpsEditFieldLabel.Text = 'Avg vel (p/s)';
            app.AvgvelpsEditField = uieditfield(app.VideoTrackingTab, 'numeric');
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apptrial1_version2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end